classdef VNA_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ReaddataButton                 matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        MessageTextArea                matlab.ui.control.TextArea
        MessageTextAreaLabel           matlab.ui.control.Label
        PointsEditField                matlab.ui.control.NumericEditField
        PointsEditFieldLabel           matlab.ui.control.Label
        RBWHzEditField                 matlab.ui.control.NumericEditField
        RBWHzEditFieldLabel            matlab.ui.control.Label
        SweepStopMHzEditField          matlab.ui.control.NumericEditField
        SweepStopMHzEditFieldLabel     matlab.ui.control.Label
        SweepStartMHzEditField         matlab.ui.control.NumericEditField
        SweepStartMHzEditFieldLabel    matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        ConnectionStatusLamp           matlab.ui.control.Lamp
        ConnectionStatusLampLabel      matlab.ui.control.Label
        RemainingEditField             matlab.ui.control.EditField
        RemainingEditFieldLabel        matlab.ui.control.Label
        ElapsedEditField               matlab.ui.control.EditField
        ElapsedEditFieldLabel          matlab.ui.control.Label
        CollectedFilesListBox          matlab.ui.control.ListBox
        CollectedFilesListBoxLabel     matlab.ui.control.Label
        CurrentFilenameEditField       matlab.ui.control.EditField
        CurrentFilenameEditFieldLabel  matlab.ui.control.Label
        SavingDataLamp                 matlab.ui.control.Lamp
        SavingDataLampLabel            matlab.ui.control.Label
        SweepingLamp                   matlab.ui.control.Lamp
        SweepingLampLabel              matlab.ui.control.Label
        progress_FG                    matlab.ui.control.Label
        progress_BG                    matlab.ui.control.Label
        percentLabel                   matlab.ui.control.Label
        ProgressLabel                  matlab.ui.control.Label
        StateEditField                 matlab.ui.control.EditField
        StateEditFieldLabel            matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        device % R&S ZNB20
        visausb % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Start');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            disp('Press Start');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
               
            % TODO: Connect to visa device here
            try
                % Create VISA object (USB)
                % app.device = visa('ni', 'USB0::0x0AAD::0x01A0::102106::INSTR');
                delete(icdevicefind);

                resourceList = visadevlist;
                app.device = visadev("USB0::0x0AAD::0x01A0::102106::INSTR");
                
                disp(resourceList);
                %disp(visastatus(visausb));
                disp('Connection succesful');
                % Set timeout
            catch ME
                disp('Connection failed:');
                disp(ME.message);
        
                app.ConnectionStatusLamp.Color = [1 0 0];
            end

        end

        % Button pushed function: ReaddataButton
        function ReaddataButtonPushed(app, event)
            
            data = read(app.device,5,"uint32");
            disp(data);
            v = app.device;
            configureTerminator(v,"LF");      % recommended for R&S ZNB :contentReference[oaicite:2]{index=2}
            v.Timeout = 10;
            
            idn = strtrim(writeread(v,"*IDN?"));   % *IDN? is standard :contentReference[oaicite:3]{index=3}
            disp(idn);
            
            % Example: single sweep on channel 1
            writeline(v,"INIT1:CONT OFF");
            writeline(v,"INIT1");                  % start a new sweep in ch1 :contentReference[oaicite:4]{index=4}
            
            % Wait for completion (sync)
            ok = strtrim(writeread(v,"*OPC?"));    % returns "1" when done :contentReference[oaicite:5]{index=5}
            disp(ok);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.SweepStartMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStartMHzEditFieldLabel.Text = 'Sweep Start (MHz)';
            app.SweepStartMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SweepStopMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStopMHzEditFieldLabel.Text = 'Sweep Stop (MHz)';
            app.SweepStopMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RBWHzEditFieldLabel = uilabel(app.LeftPanel);
            app.RBWHzEditFieldLabel.Text = 'RBW (Hz)';
            app.RBWHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointsEditFieldLabel = uilabel(app.LeftPanel);
            app.PointsEditFieldLabel.Text = '# Points ';
            app.PointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MessageTextAreaLabel = uilabel(app.LeftPanel);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.LeftPanel);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ReaddataButton = uibutton(app.LeftPanel, 'push');
            app.ReaddataButton.ButtonPushedFcn = createCallbackFcn(app, @ReaddataButtonPushed, true);
            app.ReaddataButton.Text = 'Read data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Total Data Collected')
            xlabel(app.UIAxes, 'Time (hr)')
            ylabel(app.UIAxes, 'Data (MB)')
            zlabel(app.UIAxes, 'Z')
            app.StateEditFieldLabel = uilabel(app.RightPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.RightPanel, 'text');
            app.ProgressLabel = uilabel(app.RightPanel);
            app.ProgressLabel.Text = 'Progress:';
            app.percentLabel = uilabel(app.RightPanel);
            app.percentLabel.Text = '0%';
            app.progress_BG = uilabel(app.RightPanel);
            app.progress_BG.Text = '';
            app.progress_FG = uilabel(app.RightPanel);
            app.progress_FG.Text = '';
            app.SweepingLampLabel = uilabel(app.RightPanel);
            app.SweepingLampLabel.Text = 'Sweeping';
            app.SweepingLamp = uilamp(app.RightPanel);
            app.SavingDataLampLabel = uilabel(app.RightPanel);
            app.SavingDataLampLabel.Text = 'Saving Data';
            app.SavingDataLamp = uilamp(app.RightPanel);
            app.CurrentFilenameEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentFilenameEditFieldLabel.Text = 'Current Filename';
            app.CurrentFilenameEditField = uieditfield(app.RightPanel, 'text');
            app.CollectedFilesListBoxLabel = uilabel(app.RightPanel);
            app.CollectedFilesListBoxLabel.Text = 'Collected Files';
            app.CollectedFilesListBox = uilistbox(app.RightPanel);
            app.CollectedFilesListBox.Items = {''};
            app.CollectedFilesListBox.Value = '';
            app.ElapsedEditFieldLabel = uilabel(app.RightPanel);
            app.ElapsedEditFieldLabel.Text = 'Elapsed';
            app.ElapsedEditField = uieditfield(app.RightPanel, 'text');
            app.RemainingEditFieldLabel = uilabel(app.RightPanel);
            app.RemainingEditFieldLabel.Text = 'Remaining';
            app.RemainingEditField = uieditfield(app.RightPanel, 'text');
            app.ConnectionStatusLampLabel = uilabel(app.RightPanel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VNA_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end