classdef VNA_control_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RightPanel                     matlab.ui.container.Panel
        ConnectionStatusLamp           matlab.ui.control.Lamp
        ConnectionStatusLampLabel      matlab.ui.control.Label
        RemainingEditField             matlab.ui.control.EditField
        RemainingEditFieldLabel        matlab.ui.control.Label
        ElapsedEditField               matlab.ui.control.EditField
        ElapsedEditFieldLabel          matlab.ui.control.Label
        CollectedFilesListBox          matlab.ui.control.ListBox
        CollectedFilesListBoxLabel     matlab.ui.control.Label
        CurrentFilenameEditField       matlab.ui.control.EditField
        CurrentFilenameEditFieldLabel  matlab.ui.control.Label
        SavingDataLamp                 matlab.ui.control.Lamp
        SavingDataLampLabel            matlab.ui.control.Label
        SweepingLamp                   matlab.ui.control.Lamp
        SweepingLampLabel              matlab.ui.control.Label
        progress_FG                    matlab.ui.control.Label
        progress_BG                    matlab.ui.control.Label
        percentLabel                   matlab.ui.control.Label
        ProgressLabel                  matlab.ui.control.Label
        StateEditField                 matlab.ui.control.EditField
        StateEditFieldLabel            matlab.ui.control.Label
        sParamAxes                     matlab.ui.control.UIAxes
        dataCollectAxes                matlab.ui.control.UIAxes
        LeftPanel                      matlab.ui.container.Panel
        PowerdBmEditField              matlab.ui.control.NumericEditField
        PowerdBmEditFieldLabel         matlab.ui.control.Label
        RuntimehrEditField             matlab.ui.control.NumericEditField
        RuntimehrEditFieldLabel        matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        MessageTextArea                matlab.ui.control.TextArea
        MessageTextAreaLabel           matlab.ui.control.Label
        PointsEditField                matlab.ui.control.NumericEditField
        PointsEditFieldLabel           matlab.ui.control.Label
        RBWHzEditField                 matlab.ui.control.NumericEditField
        RBWHzEditFieldLabel            matlab.ui.control.Label
        SweepStopMHzEditField          matlab.ui.control.NumericEditField
        SweepStopMHzEditFieldLabel     matlab.ui.control.Label
        SweepStartMHzEditField         matlab.ui.control.NumericEditField
        SweepStartMHzEditFieldLabel    matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % R&S ZNB20
        visausb % Description
        sParamLine   % line handle
        fileSizeLine % line handle
        %appTimer   % timer object (countdown)
        timeRemaining % seconds
        timeElapsed
        progress % percentage
        fsmState string = "IDLE"
        runStartTime datetime
        runDuration_s double = 0
        sweepIdx double = 0
        fsmTimer timer
        sweepStartTime datetime
        saveFolder string = "../VNA_data"

        fileSizeX
        fileSizeY
        

        isStopped logical = true % Description
        percent % Description
    end
    
    
    methods (Access = private)
        
        % function countdownCallback(app)
        %     disp('tick');
        %     if app.timeRemaining > 0
        %         app.timeRemaining = app.timeRemaining - 1;
        % 
        %         % elapsed
        %         app.timeElapsed = app.timeElapsed + 1;
        % 
        %         % format remaining
        %         hours   = floor( app.timeRemaining / 3600);
        %         minutes = floor(mod( app.timeRemaining, 3600) / 60);
        %         seconds = mod( app.timeRemaining, 60);
        %         app.RemainingEditField.Value = sprintf('%02d:%02d:%02d', hours, minutes, seconds);
        % 
        %         % format elapsed
        %         hours   = floor( app.timeElapsed / 3600);
        %         minutes = floor(mod( app.timeElapsed, 3600) / 60);
        %         seconds = mod( app.timeElapsed, 60);
        %         app.ElapsedEditField.Value = sprintf('%02d:%02d:%02d', hours, minutes, seconds);
        % 
        %         % update progress bar + label
        %         app.progress = round(app.timeElapsed / (3600*app.RuntimehrEditField.Value),2);
        %         app.percentLabel.Text = strcat(num2str(1e2*app.progress),'%');
        %         app.progress_FG.Position(3) = max(10,max(0,floor(app.progress*app.progress_BG.Position(3))-6));
        %     else
        %         stop(app.appTimer);
        %         delete(app.appTimer);
        %     end
        % 
        % end
        function stopRun(app)
            % Safe stop that does NOT require an event argument
            app.fsmState = "STOPPED";
            app.StateEditField.Value = "STOPPED";
            app.isStopped = true;
            app.percent = 100;
            app.percentLabel.Text = sprintf('%d%%', app.percent);

            % stop FSM timer
            if ~isempty(app.fsmTimer) && isvalid(app.fsmTimer)
                stop(app.fsmTimer);
                delete(app.fsmTimer);
            end
            % (optional) stop countdown timer too
            % if ~isempty(app.appTimer) && isvalid(app.appTimer)
            %     stop(app.appTimer);
            %     delete(app.appTimer);
            % end
        
            % lamps
            app.SweepingLamp.Color  = 'r';
            app.SavingDataLamp.Color = 'r';
        end
        
        function fsmTick(app)
            try
                % ---- stop condition: runtime elapsed ----
                app.timeElapsed = seconds(datetime("now") - app.runStartTime);
        
                % update UI runtime fields
                app.ElapsedEditField.Value = char(duration(0,0,floor(app.timeElapsed),'Format','hh:mm:ss'));
                remaining = max(0, app.runDuration_s - app.timeElapsed);
                app.RemainingEditField.Value = char(duration(0,0,floor(remaining),'Format','hh:mm:ss'));
        
                app.StateEditField.Value = char(app.fsmState);

                % ----- Progress calculation -----
                % if app.runDuration_s > 0
                %     app.progress = min(1, elapsed_s / app.runDuration_s);
                % else
                %     app.progress = 0;
                % end
                % app.percentLabel.Text = string(round(100*app.progress)) + "%";
                % app.percentLabel.Text = sprintf('%.1f%%', 100*app.progress);
                
                % % Update progress bar width
                % bgWidth = app.progress_BG.Position(3);
                % app.progress_FG.Position(3) = max(10, floor(app.progress * bgWidth));
                % ----- Progress calculation -----
                if app.runDuration_s > 0
                    rawPercent = 100 * (app.timeElapsed / app.runDuration_s);
                else
                    rawPercent = 0;
                end
                
                if app.isStopped
                    app.percent = 100;
                else
                    app.percent = min(99, floor(rawPercent));  % cap at 99 while running
                end
                % app.percentLabel.iText = string(round(100*app.progress)) + "%";

                app.percentLabel.Text = sprintf('%d%%', app.percent);
                
                % Update progress bar
                bgWidth = app.progress_BG.Position(3);
                app.progress_FG.Position(3) = max(10, floor((app.percent/100) * bgWidth)-6);
        
                switch app.fsmState
        
                    case "START_SWEEP"
                        app.timeElapsed = seconds(datetime("now") - app.runStartTime);
                        if app.timeElapsed >= app.runDuration_s
                            app.stopRun();
                            disp("Runtime finished (not starting another sweep).");
                            return;
                        end
                        app.SweepingLamp.Color = 'g';
        
                        % apply sweep settings (channel 2)
                        fStart_Hz = app.SweepStartMHzEditField.Value * 1e6;
                        fStop_Hz  = app.SweepStopMHzEditField.Value  * 1e6;
                        nPts      = max(1, round(app.PointsEditField.Value));
                        ifbw_Hz   = app.RBWHzEditField.Value;
        
                        writeline(app.device, sprintf("SENS2:FREQ:STAR %g", fStart_Hz));
                        writeline(app.device, sprintf("SENS2:FREQ:STOP %g", fStop_Hz));
                        writeline(app.device, sprintf("SENS2:SWE:POIN %d", nPts));
                        if ~isnan(ifbw_Hz) && ifbw_Hz > 0
                            writeline(app.device, sprintf("SENS2:BAND %g", ifbw_Hz));
                        end
        
                        % single sweep trigger
                        writeline(app.device, "INIT2:CONT OFF");
                        writeline(app.device, "INIT2:IMM");   % start now
        
                        app.sweepStartTime = datetime("now");
                        app.fsmState = "WAIT_SWEEP_DONE";
        
                    case "WAIT_SWEEP_DONE"
                        oldT = app.device.Timeout;
                        done = false;
        
                        % short "probe" timeout so UI stays responsive
                        app.device.Timeout = 1;
        
                        try
                            resp = strtrim(writeread(app.device, "*OPC?"));
                            done = strcmp(resp, "1");
                        catch
                            done = false;
                        end
        
                        % always restore timeout
                        app.device.Timeout = oldT;
        
                        if done
                            app.fsmState = "READ_SAVE";
                        end
        
                    case "READ_SAVE"
                        app.read_save_data();
        
                        % loop
                        % loop OR stop (after saving this sweep)
                        app.timeElapsed = seconds(datetime("now") - app.runStartTime);
                        if app.timeElapsed >= app.runDuration_s
                            app.stopRun();
                            disp("Runtime finished (saved final sweep).");
                            return;
                        else
                            app.fsmState = "START_SWEEP";
                        end
        
                    otherwise
                        % IDLE / STOPPED: do nothing
                end
        
            catch ME
                disp("FSM error:");
                disp(getReport(ME, "extended"));   % shows line number + stack
                app.stopRun();
            
            end
        end
        
        
        function read_save_data(app)
            app.SavingDataLamp.Color ='g'; 
            % Make timeout large for big ASCII transfers
            app.device.Timeout = 60;

        
            % (Optional but helpful) ensure ASCII data
            writeline(app.device,"FORM:DATA ASC");
            timestamp = datetime("now");
            timestampStr = string(timestamp);
            % -------------------------------
            % Read Bluefors temperatures
            % -------------------------------
            try
                baseURL = "http://127.0.0.1:49099/values/";
            
                url_c1 = baseURL + "mapper.heater_mappings_bftc.device.c1/temperature";
                url_c2 = baseURL + "mapper.heater_mappings_bftc.device.c2/temperature";
                url_c5 = baseURL + "mapper.heater_mappings_bftc.device.c5/temperature";
                url_c6 = baseURL + "mapper.heater_mappings_bftc.device.c6/temperature";
            
                r1 = webread(url_c1);
                r2 = webread(url_c2);
                r5 = webread(url_c5);
                r6 = webread(url_c6);
            
                T_c1 = r1.data.mapper_heater_mappings_bftc_device_c1_temperature.content.latest_value.value
                % return
                T_c2 = r2.data.mapper_heater_mappings_bftc_device_c2_temperature.content.latest_value.value;
                T_c5 = r5.data.mapper_heater_mappings_bftc_device_c5_temperature.content.latest_value.value;
                T_c6 = r6.data.mapper_heater_mappings_bftc_device_c6_temperature.content.latest_value.value;
           
            catch ME
                disp("Bluefors read error:");
                disp(ME.message);
            
                T_c1 = NaN;
                T_c2 = NaN;
                T_c5 = NaN;
                T_c6 = NaN;
            end
            % Frequency axis (stimulus)
            ftxt = strtrim(writeread(app.device,"CALC2:DATA:STIM?"));   % stimulus values :contentReference[oaicite:2]{index=2}
            fHz = sscanf(strrep(ftxt,',',' '), '%f');
        
            % Complex trace data (Re/Im interleaved)
            dtxt = strtrim(writeread(app.device,"CALC2:DATA? SDAT"));   % Re/Im pairs :contentReference[oaicite:3]{index=3}
            y = sscanf(strrep(dtxt,',',' '), '%f');
        
            re = y(1:2:end);
            im = y(2:2:end);
            s  = complex(re, im);
        
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            fHz = fHz(1:n);
            s   = s(1:n);
            % disp(fHz);
            % disp(s);
            disp("done read");
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            % return
            fHz = fHz(1:n);
            s   = s(1:n);
            
            
            % Convert to useful quantities
            mag_dB = 20*log10(abs(s));
            phase_deg = angle(s)*180/pi;
            real_part = real(s);
            imag_part = imag(s);
            
            % Create table

            % T = table( ...
            %    repmat(timestamp, n, 1), ...
            %    fHz, real_part, imag_part, mag_dB, phase_deg, ...
            %    'VariableNames', {'Timestamp','Frequency_Hz','Real','Imag','Magnitude_dB','Phase_deg'});
            % T = table( ...
            %     repmat(timestamp, n, 1), ...
            %     fHz, ...
            %     real_part, ...
            %     imag_part, ...
            %     mag_dB, ...
            %     phase_deg, ...
            %     repmat(T_c1, n, 1), ...
            %     repmat(T_c2, n, 1), ...
            %     repmat(T_c5, n, 1), ...
            %     repmat(T_c6, n, 1), ...
            %     'VariableNames', { ...
            %     'Timestamp', ...
            %     'Frequency_Hz', ...
            %     'Real', ...
            %     'Imag', ...
            %     'Magnitude_dB', ...
            %     'Phase_deg', ...
            %     'Temp_C1_K', ...
            %     'Temp_C2_K', ...
            %     'Temp_C5_K', ...
            %     'Temp_C6_K'});
            % % Create filename with timestamp
            % filename = "../VNA_data/ZNB_trace_" + datestr(now,'yyyymmdd_HHMMSS') + ".csv";
            % 
            % % Save file
            % writetable(T, filename);
            % 
            % disp("Saved file:");
            % disp(filename);

            % Create filename with timestamp
            filename = "../VNA_data/ZNB_trace_" + datestr(now,'yyyymmdd_HHMMSS') + ".csv";

            
            % Open file manually
            fid = fopen(filename, 'w')
            disp([T_c1 T_c2 T_c5 T_c6])
            % return
            % Write metadata header
            fprintf(fid, 'Measurement Timestamp: %s\n', string(timestamp));
            fprintf(fid, 'Bluefors Temperatures (K):\n');
    

            fprintf(fid, 'C1: %s\n', T_c1);
            fprintf(fid, 'C2: %s\n', T_c2);
            fprintf(fid, 'C5: %s\n', T_c5);
            fprintf(fid, 'C6: %s\n', T_c6);
            fprintf(fid, '\n');
            % Write column header
            fprintf(fid, 'Frequency_Hz,Real,Imag,Magnitude_dB,Phase_deg\n');
            
            % Write numeric data
            for k = 1:n
                fprintf(fid, '%.12e,%.12e,%.12e,%.6f,%.6f\n', ...
                    fHz(k), real_part(k), imag_part(k), mag_dB(k), phase_deg(k));
            end
            
            fclose(fid);
            
            disp("Saved file with header:");
            disp(filename);
            app.SavingDataLamp.Color = 'r';

            % read final file size 
            fileSize = dir(filename).bytes/1e6;

            % add stored data to plot
            last = 0;
            if(length(app.fileSizeY) > 0)
                last = app.fileSizeY(end);
            end
            app.fileSizeX = [app.fileSizeX app.timeElapsed/3600]
            app.fileSizeY = [app.fileSizeY (fileSize+last)]

            app.fileSizeLine = plot(app.dataCollectAxes,app.fileSizeX,app.fileSizeY,LineWidth=2,Color='#ff4500 ');

            % Plot in app axes
            app.sParamLine = plot(app.sParamAxes,fHz,mag_dB);

            % update UI list
            app.CurrentFilenameEditField.Value = filename;
            items = string(app.CollectedFilesListBox.Items);
            if numel(items)==1 && strlength(items(1))==0
                items = strings(0,1);
            end
            items(end+1) = filename;
            app.CollectedFilesListBox.Items = cellstr(items);
            app.CollectedFilesListBox.Value = char(filename);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Start');
            % start timer

            % update warning
            selection = uiconfirm(app.UIFigure, ...
            'Check for Windows auto-update BEFORE starting collection!', ...
            'Warning', ...
            'Options', {'OK, I checked'}, ...
            'Icon', 'warning');

            % uialert(app.UIFigure, 'Check for Windows auto-update BEFORE starting collection!!', ...
            % 'WARNING Message','Icon','warning',);

            app.timeRemaining = 3600*app.RuntimehrEditField.Value;
            app.timeElapsed = 0;

            disp(app.timeRemaining);
            app.ConnectButton.Enable = "on";

            app.fileSizeX = [];
            app.fileSizeY = [];

            % check 'VNA_DATA' folder exists, if not, create it
            files = dir;
            files= dir('..');
            found=0;
            for f=1:length(files)
                file = files(f).name;
                if(contains(file,'VNA_DATA')&&~contains(file,'ARCHIVE'))
                    found=1;
                    break;
                end
            end
            if(~found)
                mkdir ../VNA_DATA
            end


            %app.appTimer = timer( ...
            %    'ExecutionMode', 'fixedRate', ...
            %    'Period', 1, ...                 % seconds
            %    'TimerFcn', @(~,~) countdownCallback(app));
        
            %start(app.appTimer);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.device)
                disp("Not connected.");
                return;
            end

            % disable runtime edit
            app.RuntimehrEditField.Enable = "off";
        
            % runtime from UI (hours → seconds)
            app.runDuration_s = 3600 * app.RuntimehrEditField.Value;
            app.runStartTime = datetime("now");
            app.sweepIdx = 0;
            app.ConnectButton.Enable = 'off';

            % initialize FSM
            app.fsmState = "START_SWEEP";
            app.StateEditField.Value = char(app.fsmState);
            app.isStopped = false;
        
            % create timer (FSM “heartbeat”)
            if ~isempty(app.fsmTimer) && isvalid(app.fsmTimer)
                stop(app.fsmTimer); delete(app.fsmTimer);
            end
        
            app.fsmTimer = timer( ...
                "ExecutionMode","fixedSpacing", ...
                "Period", 0.25, ...   % 4 Hz
                "TimerFcn", @(~,~) app.fsmTick() );
        
            start(app.fsmTimer);
            app.StopButton.Enable = 'on';
      
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.device = visadev("USB0::0x0AAD::0x01A0::102106::INSTR");
                configureTerminator(app.device,"LF");
                app.device.Timeout = 10;
        
                idn = strtrim(writeread(app.device,"*IDN?"));
                disp(idn);
        
                app.ConnectionStatusLamp.Color = [0 1 0];
                app.StartButton.Enable = 'on';
            catch ME
                disp(ME.message);
                app.ConnectionStatusLamp.Color = [1 0 0];
            end 
            

        end

        % Callback function
        function ReaddataButtonPushed(app, event)
            app.SavingDataLamp.Color ='g'; 
            % Make timeout large for big ASCII transfers
            app.device.Timeout = 60;

        
            % (Optional but helpful) ensure ASCII data
            writeline(app.device,"FORM:DATA ASC");
            timestamp = datetime("now");
            timestampStr = string(timestamp);
            % -------------------------------
            % Read Bluefors temperatures
            % -------------------------------
            try
                baseURL = "http://127.0.0.1:49099/values/";
            
                url_c1 = baseURL + "mapper.heater_mappings_bftc.device.c1/temperature";
                url_c2 = baseURL + "mapper.heater_mappings_bftc.device.c2/temperature";
                url_c5 = baseURL + "mapper.heater_mappings_bftc.device.c5/temperature";
                url_c6 = baseURL + "mapper.heater_mappings_bftc.device.c6/temperature";
            
                r1 = webread(url_c1);
                r2 = webread(url_c2);
                r5 = webread(url_c5);
                r6 = webread(url_c6);
            
                T_c1 = r1.data.mapper_heater_mappings_bftc_device_c1_temperature.content.latest_value.value
                % return
                T_c2 = r2.data.mapper_heater_mappings_bftc_device_c2_temperature.content.latest_value.value;
                T_c5 = r5.data.mapper_heater_mappings_bftc_device_c5_temperature.content.latest_value.value;
                T_c6 = r6.data.mapper_heater_mappings_bftc_device_c6_temperature.content.latest_value.value;
           
            catch ME
                disp("Bluefors read error:");
                disp(ME.message);
            
                T_c1 = NaN;
                T_c2 = NaN;
                T_c5 = NaN;
                T_c6 = NaN;
            end
            % Frequency axis (stimulus)
            ftxt = strtrim(writeread(app.device,"CALC2:DATA:STIM?"));   % stimulus values :contentReference[oaicite:2]{index=2}
            fHz = sscanf(strrep(ftxt,',',' '), '%f');
        
            % Complex trace data (Re/Im interleaved)
            dtxt = strtrim(writeread(app.device,"CALC2:DATA? SDAT"));   % Re/Im pairs :contentReference[oaicite:3]{index=3}
            y = sscanf(strrep(dtxt,',',' '), '%f');
        
            re = y(1:2:end);
            im = y(2:2:end);
            s  = complex(re, im);
        
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            fHz = fHz(1:n);
            s   = s(1:n);
            disp(fHz);
            % disp(s);
            disp("done read");
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            % return
            fHz = fHz(1:n);
            s   = s(1:n);
            
            
            % Convert to useful quantities
            mag_dB = 20*log10(abs(s));
            phase_deg = angle(s)*180/pi;
            real_part = real(s);
            imag_part = imag(s);
            
            % Create table

            % T = table( ...
            %    repmat(timestamp, n, 1), ...
            %    fHz, real_part, imag_part, mag_dB, phase_deg, ...
            %    'VariableNames', {'Timestamp','Frequency_Hz','Real','Imag','Magnitude_dB','Phase_deg'});
            T = table( ...
                repmat(timestamp, n, 1), ...
                fHz, ...
                real_part, ...
                imag_part, ...
                mag_dB, ...
                phase_deg, ...
                repmat(T_c1, n, 1), ...
                repmat(T_c2, n, 1), ...
                repmat(T_c5, n, 1), ...
                repmat(T_c6, n, 1), ...
                'VariableNames', { ...
                'Timestamp', ...
                'Frequency_Hz', ...
                'Real', ...
                'Imag', ...
                'Magnitude_dB', ...
                'Phase_deg', ...
                'Temp_C1_K', ...
                'Temp_C2_K', ...
                'Temp_C5_K', ...
                'Temp_C6_K'});
            % Create filename with timestamp
            filename = "../VNA_data/ZNB_trace_" + datestr(now,'yyyymmdd_HHMMSS') + ".csv";
            
            % Save file
            writetable(T, filename);
            
            disp("Saved file:");
            disp(filename);

            
            % Open file manually
            fid = fopen(filename, 'w');
            disp([T_c1 T_c2 T_c5 T_c6])
            % return
            % Write metadata header
            fprintf(fid, 'Measurement Timestamp: %s\n', string(timestamp));
            fprintf(fid, 'Bluefors Temperatures (K):\n');
    

            fprintf(fid, 'C1: %s\n', T_c1);
            fprintf(fid, 'C2: %s\n', T_c2);
            fprintf(fid, 'C5: %s\n', T_c5);
            fprintf(fid, 'C6: %s\n', T_c6);
            fprintf(fid, '\n')
            % Write column header
            fprintf(fid, 'Frequency_Hz,Real,Imag,Magnitude_dB,Phase_deg\n');
            
            % Write numeric data
            for k = 1:n
                fprintf(fid, '%.12e,%.12e,%.12e,%.6f,%.6f\n', ...
                    fHz(k), real_part(k), imag_part(k), mag_dB(k), phase_deg(k));
            end
            
            fclose(fid);
            
            disp("Saved file with header:");
            disp(filename);
            app.SavingDataLamp.Color = 'r';

            % Plot in app axes
            app.sParamLine = plot(app.sParamAxes,fHz,mag_dB);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopRun();

        end

        % Value changed function: RuntimehrEditField
        function RuntimehrEditFieldValueChanged(app, event)
            value = app.RuntimehrEditField.Value;
            app.timeRemaining = value*3600;
        end

        % Value changed function: PowerdBmEditField
        function PowerdBmEditFieldValueChanged(app, event)
            value = app.PowerdBmEditField.Value;
            % TODO
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SweepStartMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStartMHzEditFieldLabel.Text = 'Sweep Start (MHz)';
            app.SweepStartMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SweepStartMHzEditField.Value = 60;
            app.SweepStopMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStopMHzEditFieldLabel.Text = 'Sweep Stop (MHz)';
            app.SweepStopMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SweepStopMHzEditField.Value = 67;
            app.RBWHzEditFieldLabel = uilabel(app.LeftPanel);
            app.RBWHzEditFieldLabel.Text = 'RBW (Hz)';
            app.RBWHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RBWHzEditField.Value = 200;
            app.PointsEditFieldLabel = uilabel(app.LeftPanel);
            app.PointsEditFieldLabel.Text = '# Points ';
            app.PointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointsEditField.Value = 100000;
            app.MessageTextAreaLabel = uilabel(app.LeftPanel);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.LeftPanel);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.RuntimehrEditFieldLabel = uilabel(app.LeftPanel);
            app.RuntimehrEditFieldLabel.Text = 'Runtime (hr)';
            app.RuntimehrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RuntimehrEditField.ValueChangedFcn = createCallbackFcn(app, @RuntimehrEditFieldValueChanged, true);
            app.RuntimehrEditField.Value = 22;
            app.PowerdBmEditFieldLabel = uilabel(app.LeftPanel);
            app.PowerdBmEditFieldLabel.Enable = 'off';
            app.PowerdBmEditFieldLabel.Text = 'Power (dBm)';
            app.PowerdBmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PowerdBmEditField.ValueChangedFcn = createCallbackFcn(app, @PowerdBmEditFieldValueChanged, true);
            app.PowerdBmEditField.Editable = 'off';
            app.PowerdBmEditField.Enable = 'off';
            app.RightPanel = uipanel(app.UIFigure);
            app.dataCollectAxes = uiaxes(app.RightPanel);
            title(app.dataCollectAxes, 'Total Data Collected')
            xlabel(app.dataCollectAxes, 'Time (hr)')
            ylabel(app.dataCollectAxes, 'Data (MB)')
            zlabel(app.dataCollectAxes, 'Z')
            app.sParamAxes = uiaxes(app.RightPanel);
            title(app.sParamAxes, 'S21')
            xlabel(app.sParamAxes, 'Frequency (MHz)')
            ylabel(app.sParamAxes, 'dB(|S21|)')
            zlabel(app.sParamAxes, 'Z')
            app.StateEditFieldLabel = uilabel(app.RightPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.RightPanel, 'text');
            app.ProgressLabel = uilabel(app.RightPanel);
            app.ProgressLabel.Text = 'Progress:';
            app.percentLabel = uilabel(app.RightPanel);
            app.percentLabel.Text = '0%';
            app.progress_BG = uilabel(app.RightPanel);
            app.progress_BG.Text = '';
            app.progress_FG = uilabel(app.RightPanel);
            app.progress_FG.Text = '';
            app.SweepingLampLabel = uilabel(app.RightPanel);
            app.SweepingLampLabel.Text = 'Sweeping';
            app.SweepingLamp = uilamp(app.RightPanel);
            app.SavingDataLampLabel = uilabel(app.RightPanel);
            app.SavingDataLampLabel.Text = 'Saving Data';
            app.SavingDataLamp = uilamp(app.RightPanel);
            app.CurrentFilenameEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentFilenameEditFieldLabel.Text = 'Current Filename';
            app.CurrentFilenameEditField = uieditfield(app.RightPanel, 'text');
            app.CollectedFilesListBoxLabel = uilabel(app.RightPanel);
            app.CollectedFilesListBoxLabel.Text = 'Collected Files';
            app.CollectedFilesListBox = uilistbox(app.RightPanel);
            app.CollectedFilesListBox.Items = {''};
            app.CollectedFilesListBox.Value = '';
            app.ElapsedEditFieldLabel = uilabel(app.RightPanel);
            app.ElapsedEditFieldLabel.Text = 'Elapsed';
            app.ElapsedEditField = uieditfield(app.RightPanel, 'text');
            app.RemainingEditFieldLabel = uilabel(app.RightPanel);
            app.RemainingEditFieldLabel.Text = 'Remaining';
            app.RemainingEditField = uieditfield(app.RightPanel, 'text');
            app.ConnectionStatusLampLabel = uilabel(app.RightPanel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VNA_control_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end